classdef curvas_caracteristicas_motores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RshKnob                         matlab.ui.control.Knob
        RshKnobLabel                    matlab.ui.control.Label
        KdelflujoEditField              matlab.ui.control.NumericEditField
        KdelflujoEditFieldLabel         matlab.ui.control.Label
        KdelmotorEditField              matlab.ui.control.NumericEditField
        KdelmotorEditFieldLabel         matlab.ui.control.Label
        RbKnob                          matlab.ui.control.Knob
        RbKnobLabel                     matlab.ui.control.Label
        PotencianominalwEditField       matlab.ui.control.NumericEditField
        PotencianominalwEditFieldLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        VaKnob                          matlab.ui.control.Knob
        VaKnobLabel                     matlab.ui.control.Label
        RsKnob                          matlab.ui.control.Knob
        RsLabel                         matlab.ui.control.Label
        RaKnob                          matlab.ui.control.Knob
        RaLabel                         matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Rs=0;
        Rb=0;
        Ra=0;
        Rsh=5;
        pot=1;
        va=0.01;
        kmot=0.01;
        kflu=0.01;
        escoger=1;
    end

    methods (Access = private)
        
        function plotea(app)
            
            if app.escoger==1
                %Curva Eg vs Ia
                Ianom=(app.pot)/app.va;
                Eg0 = 0.00 * app.va;
                Ia=linspace(0.00,1.5*Ianom,2000);
                Egnom=app.va-Ianom*(app.Rs+app.Ra+app.Rb);  
                Eg = Eg0 + (Egnom - Eg0) * (Ia / Ianom).^(1 / 4);
                Eg(Eg < 0) = 0;
                cla(app.UIAxes_2);
                plot(app.UIAxes_2,Ia, Eg, 'b', 'LineWidth', 2);
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,0, 0, 'ro', 'MarkerFaceColor', 'r');                     % Punto inicial
                plot(app.UIAxes_2,Ianom, Egnom, 'ro', 'MarkerFaceColor', 'r');              % Punto nominal
                xline(app.UIAxes_2,Ianom, '--r', 'I_{nom}', 'LabelVerticalAlignment','bottom','LabelHorizontalAlignment','right');
                ylim(app.UIAxes_2,[0 app.va+10]);
    
                %Curva Rpm vs Ia
                numera=(app.va-Ia*(app.Rs+app.Ra+app.Rb));
                denomi=(Ia*app.kmot*app.kflu);
                RPM=numera./denomi;
                RPM_dif=RPM;
                RPM_dif(RPM_dif < 0) = NaN;
                dRPM = diff(RPM_dif) ./ diff(Ia);
                umbral = 1e7;
                filtro = [true, abs(dRPM) < umbral];
                RPM_filtrado = RPM_dif;
                RPM_filtrado(~filtro) = NaN;
                cla(app.UIAxes_3);
                plot(app.UIAxes_3,Ia,RPM_filtrado, 'b', 'LineWidth', 2)
                
                %Curva torque vs Rpm
                potint=Eg.*Ia;
                velang=RPM*pi/30;
                torque=potint./velang;
                cla(app.UIAxes);
                plot(app.UIAxes,torque,RPM_filtrado, 'b', 'LineWidth', 2)
                
                %Curva Torque vs Ia
                torquek=app.kflu*app.kmot*Ia.^2;
                cla(app.UIAxes_4);
                plot(app.UIAxes_4,Ia,torquek, 'b', 'LineWidth', 2);
                
            elseif app.escoger==2    
                
               
                 %Curva Eg vs Ia
                Ianom=((app.pot)/app.va)-(app.va/app.Rsh);
                Ia=linspace(0.00,1.5*Ianom,2000);
                Egnom=app.va-Ianom*(app.Ra+app.Rb);  
                a = (app.va - Egnom) / Ianom^4;
                Eg = app.va - a .* Ia.^4;
                Eg(Eg < 0) = 0;
                cla(app.UIAxes_2);
                plot(app.UIAxes_2,Ia, Eg, 'b', 'LineWidth', 2);
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,0, app.va, 'ro', 'MarkerFaceColor', 'r');                     % Punto inicial
                plot(app.UIAxes_2,Ianom, Egnom, 'ro', 'MarkerFaceColor', 'r');              % Punto nominal
                xline(app.UIAxes_2,Ianom, '--r', 'I_{nom}', 'LabelVerticalAlignment','bottom','LabelHorizontalAlignment','right');
                ylim(app.UIAxes_2,[0 app.va+10]);
                
                %Curva Rpm vs Ia
                numera=(app.va-Ia*(app.Ra+app.Rb));
                denomi=(app.va/app.Rsh)*app.kflu*app.kmot;
                RPM=numera./denomi;
                cla(app.UIAxes_3);
                plot(app.UIAxes_3,Ia,RPM, 'b', 'LineWidth', 2)
                
                %Curva torque vs Rpm
                Egn=app.va-Ia*(app.Ra+app.Rb);
                potint=Egn.*Ia;
                velang=RPM*pi/30;
                torque=potint./velang;
                cla(app.UIAxes);
                plot(app.UIAxes,torque,RPM, 'b', 'LineWidth', 2)
                
                %Curva Torque vs Ia
                torquek=app.kflu*app.kmot*Ia;
                cla(app.UIAxes_4);
                plot(app.UIAxes_4,Ia,torquek, 'b', 'LineWidth', 2)
                
            elseif app.escoger==3
                
                 %Curva Eg vs Ia
                Ianom=((app.pot)/app.va)-((app.va-(app.pot/app.va)*app.Rs)/app.Rsh);
                Ia=linspace(0.00,1.5*Ianom,2000);
                Egnom=(app.va-(app.pot/app.va)*app.Rs)-Ianom*(app.Ra+app.Rb); 
                Eg0 = app.va * app.Rsh / (app.Rsh + app.Rs); 
                a = (Egnom-Eg0) / Ianom^4;  
                Eg = Eg0 + a .* Ia.^4;
                Eg(Eg < 0) = 0;
                cla(app.UIAxes_2);
                plot(app.UIAxes_2,Ia, Eg, 'b', 'LineWidth', 2);
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,0, Eg0, 'ro', 'MarkerFaceColor', 'r');                     % Punto inicial
                plot(app.UIAxes_2,Ianom, Egnom, 'ro', 'MarkerFaceColor', 'r');              % Punto nominal
                xline(app.UIAxes_2,Ianom, '--r', 'I_{nom}', 'LabelVerticalAlignment','bottom','LabelHorizontalAlignment','right');
                ylim(app.UIAxes_2,[0 app.va+10]);
                
                %Curva RPM vs Ia
                % Aditiva
                numera_ad = (app.va - Ia * (app.Ra + app.Rb) - (app.pot / app.va) * app.Rs);
                denomi_ad = (((app.va- (app.pot / app.va) * app.Rs) / app.Rsh + Ia)) * app.kflu * app.kmot;
                RPM_ad = numera_ad ./ denomi_ad;
                
                % Diferencial
                numera_dif = (app.va - Ia * (app.Ra + app.Rb) - (app.pot / app.va) * app.Rs);
                denomi_dif = (((app.va - (app.pot / app.va) * app.Rs) / app.Rsh - Ia)) * app.kflu * app.kmot;
                RPM_dif = numera_dif ./ denomi_dif;
                RPM_dif(RPM_dif < 0) = NaN;
                dRPM = diff(RPM_dif) ./ diff(Ia);
                umbral = 1e8;
                filtro = [true, abs(dRPM) < umbral];
                RPM_filtrado = RPM_dif;
                RPM_filtrado(~filtro) = NaN;

                % Graficar ambas curvas
                cla(app.UIAxes_3);
                plot(app.UIAxes_3, Ia, RPM_ad, 'b', 'LineWidth', 2); hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, Ia, RPM_filtrado, 'r', 'LineWidth', 2);
                legend(app.UIAxes_3, {'Aditiva', 'Diferencial'}, 'Location', 'northeast');
                
                %Curva torque vs Rpm
                %aditivo
                Egn=(app.va-(app.pot/app.va)*app.Rs)-Ia.*(app.Ra+app.Rb);
                potint=Egn.*Ia;
                velang_ad=RPM_ad*pi/30;
                torque_ad=potint./velang_ad;
                %diferencial
                velang_dif=RPM_dif*pi/30;
                torque_dif=potint./velang_dif;
                cla(app.UIAxes);
                plot(app.UIAxes, Ia, torque_ad, 'b', 'LineWidth', 2); hold(app.UIAxes, 'on');
                plot(app.UIAxes, Ia, torque_dif, 'r', 'LineWidth', 2);
                legend(app.UIAxes, {'Aditiva', 'Diferencial'}, 'Location', 'northeast');
                
                %Curva Torque vs Ia
                %aditiva
                torquek_ad=app.kflu*app.kmot.*Ia.*(((app.va- (app.pot / app.va) * app.Rs)/app.Rsh)+Ia);
                %diferencial
                torquek_dif=app.kflu*app.kmot.*Ia.*(((app.va- (app.pot / app.va) * app.Rs)/app.Rsh)-Ia);
                %graficar
                cla(app.UIAxes_4);
                plot(app.UIAxes_4, Ia, torquek_ad, 'b', 'LineWidth', 2); hold(app.UIAxes_4, 'on');
                plot(app.UIAxes_4, Ia, torquek_dif, 'r', 'LineWidth', 2);
                legend(app.UIAxes_4, {'Aditiva', 'Diferencial'}, 'Location', 'northeast');
                
            elseif app.escoger==4
                
                 %Curva Eg vs Ia
                Ianom=((app.pot)/app.va)-(app.va/app.Rsh);
                Ia=linspace(0.00,1.5*Ianom,2000);
                Egnom=(app.va)-Ianom*(app.Ra+app.Rb+app.Rs); 
                Eg0 = app.va; 
                a = (Egnom - Eg0) / Ianom^4;  
                Eg = Eg0 + a .* Ia.^4;
                Eg(Eg < 0) = 0;
                cla(app.UIAxes_2);
                plot(app.UIAxes_2,Ia, Eg, 'b', 'LineWidth', 2);
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,0, app.va, 'ro', 'MarkerFaceColor', 'r');                     % Punto inicial
                plot(app.UIAxes_2,Ianom, Egnom, 'ro', 'MarkerFaceColor', 'r');              % Punto nominal
                xline(app.UIAxes_2,Ianom, '--r', 'I_{nom}', 'LabelVerticalAlignment','bottom','LabelHorizontalAlignment','right');
                ylim(app.UIAxes_2,[0 app.va+10]);
                
                %Curva RPM vs Ia
                % Aditiva
                numera_ad = (app.va - Ia * (app.Ra + app.Rb+app.Rs));
                denomi_ad = ((app.va / app.Rsh + Ia)) * app.kflu * app.kmot;
                RPM_ad = numera_ad ./ denomi_ad;
                
                % Diferencial
                numera_dif = (app.va - Ia * (app.Ra + app.Rb +app.Rs));
                denomi_dif = ((app.va / app.Rsh - Ia)) * app.kflu * app.kmot;
                RPM_dif = numera_dif ./ denomi_dif;
                RPM_dif(RPM_dif < 0) = NaN;
                dRPM = diff(RPM_dif) ./ diff(Ia);
                umbral = 1e8;
                filtro = [true, abs(dRPM) < umbral];
                RPM_filtrado = RPM_dif;
                RPM_filtrado(~filtro) = NaN;

                % Graficar ambas curvas
                cla(app.UIAxes_3);
                plot(app.UIAxes_3, Ia, RPM_ad, 'b', 'LineWidth', 2); hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, Ia, RPM_filtrado, 'r', 'LineWidth', 2);
                legend(app.UIAxes_3, {'Aditiva', 'Diferencial'}, 'Location', 'northeast');
                
                %Curva torque vs Rpm
                %aditivo
                Egn=(app.va)-Ia.*(app.Ra+app.Rb+app.Rs);
                potint=Egn.*Ia;
                velang_ad=RPM_ad*pi/30;
                torque_ad=potint./velang_ad;
                %diferencial
                velang_dif=RPM_dif*pi/30;
                torque_dif=potint./velang_dif;
                cla(app.UIAxes);
                plot(app.UIAxes, Ia, torque_ad, 'b', 'LineWidth', 2); hold(app.UIAxes, 'on');
                plot(app.UIAxes, Ia, torque_dif, 'r', 'LineWidth', 2);
                legend(app.UIAxes, {'Aditiva', 'Diferencial'}, 'Location', 'northeast');
                
                %Curva Torque vs Ia
                %aditiva
                torquek_ad=app.kflu*app.kmot.*Ia.*((app.va/app.Rsh)+Ia);
                %diferencial
                torquek_dif=app.kflu*app.kmot.*Ia.*((app.va/app.Rsh)-Ia);
                %graficar
                cla(app.UIAxes_4);
                plot(app.UIAxes_4, Ia, torquek_ad, 'b', 'LineWidth', 2); hold(app.UIAxes_4, 'on');
                plot(app.UIAxes_4, Ia, torquek_dif, 'r', 'LineWidth', 2);
                legend(app.UIAxes_4, {'Aditiva', 'Diferencial'}, 'Location', 'northeast');
                
            end
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "Serie"
                app.RsKnob.Enable='on';
                app.RshKnob.Enable='off';
                app.escoger=1;
            elseif value == "Shunt"
                app.RsKnob.Enable='off';
                app.RshKnob.Enable='on';
                app.escoger=2;
            elseif value == "Compound corta"
                app.RsKnob.Enable='on';
                app.RshKnob.Enable='on';
                app.escoger=3;
            elseif value == "Compound larga"
                app.RsKnob.Enable='on';
                app.RshKnob.Enable='on';
                app.escoger=4;
            end
            app.plotea();

        end

        % Value changing function: RaKnob
        function RaKnobValueChanging(app, event)

            app.Ra = event.Value;
            app.plotea();
        end

        % Value changing function: RsKnob
        function RsKnobValueChanging(app, event)

            app.Rs = event.Value;
            app.plotea();
        end

        % Value changing function: VaKnob
        function VaKnobValueChanging(app, event)

            app.va = event.Value;
            app.plotea();
        end

        % Value changed function: PotencianominalwEditField
        function PotencianominalwEditFieldValueChanged(app, event)
            app.pot = app.PotencianominalwEditField.Value;
            app.plotea();
        end

        % Value changing function: RbKnob
        function RbKnobValueChanging(app, event)
            app.Rb = event.Value;
            app.plotea();
        end

        % Value changed function: KdelmotorEditField
        function KdelmotorEditFieldValueChanged(app, event)
            app.kmot = app.KdelmotorEditField.Value;
            app.plotea()
        end

        % Value changed function: KdelflujoEditField
        function KdelflujoEditFieldValueChanged(app, event)
            app.kflu = app.KdelflujoEditField.Value;
            app.plotea()
        end

        % Value changing function: RshKnob
        function RshKnobValueChanging(app, event)
            app.Rsh = event.Value;
            app.plotea()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Curva Mecanica')
            xlabel(app.UIAxes, 'Torque (N.m)')
            ylabel(app.UIAxes, 'RPM')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Curva de exitacion')
            xlabel(app.UIAxes_2, 'Ia(A)')
            ylabel(app.UIAxes_2, 'Eg(V)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Velocidad-Corriente')
            xlabel(app.UIAxes_3, 'Ia(A)')
            ylabel(app.UIAxes_3, 'RPM')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Torque-Corriente')
            xlabel(app.UIAxes_4, 'Ia(A)')
            ylabel(app.UIAxes_4, 'Torque (N.m)')
            zlabel(app.UIAxes_4, 'Z')
            app.RaLabel = uilabel(app.UIFigure);
            app.RaLabel.Text = 'Ra Ω';
            app.RaKnob = uiknob(app.UIFigure, 'continuous');
            app.RaKnob.Limits = [0 2];
            app.RaKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.RaKnob.ValueChangingFcn = createCallbackFcn(app, @RaKnobValueChanging, true);
            app.RsLabel = uilabel(app.UIFigure);
            app.RsLabel.Text = 'Rs Ω';
            app.RsKnob = uiknob(app.UIFigure, 'continuous');
            app.RsKnob.Limits = [0 1];
            app.RsKnob.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.RsKnob.ValueChangingFcn = createCallbackFcn(app, @RsKnobValueChanging, true);
            app.VaKnobLabel = uilabel(app.UIFigure);
            app.VaKnobLabel.Text = 'Va';
            app.VaKnob = uiknob(app.UIFigure, 'continuous');
            app.VaKnob.Limits = [0.01 500];
            app.VaKnob.ValueChangingFcn = createCallbackFcn(app, @VaKnobValueChanging, true);
            app.VaKnob.Value = 1;
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Serie', 'Shunt', 'Compound corta', 'Compound larga'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Serie';
            app.PotencianominalwEditFieldLabel = uilabel(app.UIFigure);
            app.PotencianominalwEditFieldLabel.Text = 'Potencia nominal (w)';
            app.PotencianominalwEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotencianominalwEditField.ValueChangedFcn = createCallbackFcn(app, @PotencianominalwEditFieldValueChanged, true);
            app.RbKnobLabel = uilabel(app.UIFigure);
            app.RbKnobLabel.Text = 'Rb Ω';
            app.RbKnob = uiknob(app.UIFigure, 'continuous');
            app.RbKnob.Limits = [0 1];
            app.RbKnob.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.RbKnob.ValueChangingFcn = createCallbackFcn(app, @RbKnobValueChanging, true);
            app.KdelmotorEditFieldLabel = uilabel(app.UIFigure);
            app.KdelmotorEditFieldLabel.Text = 'K del motor';
            app.KdelmotorEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdelmotorEditField.ValueChangedFcn = createCallbackFcn(app, @KdelmotorEditFieldValueChanged, true);
            app.KdelflujoEditFieldLabel = uilabel(app.UIFigure);
            app.KdelflujoEditFieldLabel.Text = 'K del flujo';
            app.KdelflujoEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdelflujoEditField.ValueChangedFcn = createCallbackFcn(app, @KdelflujoEditFieldValueChanged, true);
            app.RshKnobLabel = uilabel(app.UIFigure);
            app.RshKnobLabel.Text = 'Rsh Ω';
            app.RshKnob = uiknob(app.UIFigure, 'continuous');
            app.RshKnob.Limits = [5 500];
            app.RshKnob.ValueChangingFcn = createCallbackFcn(app, @RshKnobValueChanging, true);
            app.RshKnob.Enable = 'off';
            app.RshKnob.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curvas_caracteristicas_motores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end